classdef APP_magcalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        UITable        matlab.ui.control.Table
        mag1LampLabel  matlab.ui.control.Label
        mag1Lamp       matlab.ui.control.Lamp
        mag2LampLabel  matlab.ui.control.Label
        mag2Lamp       matlab.ui.control.Lamp
        Res            matlab.ui.control.Label
        RightPanel     matlab.ui.container.Panel
        GridLayout2    matlab.ui.container.GridLayout
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        binMenu        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dataFileName % Description
        PathName
        data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: binMenu
        function Menu_2Selected(app, event)
            
            app.mag1Lamp.Color = 'white';
            app.mag2Lamp.Color = 'white';
            %% 载入数据
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','载入数据文件');
            d.Value = 0;
            if 0
                %             step1_loaddata
            else
                V1000_to_mat_mag
                if returnFlag
                    return
                end
                dataFileName = saveFileName_magCalib{i_file};
                data = load(dataFileName);
            end
            if isempty(dataFileName)
                return
            end
            %% 离线计算
            d.Value = 0.25;
            d.Message = '进行解算';
            step2_sim
            %% 显示
            d.Value = 0.5;
            d.Message = '存储标定结果';               
            step3_savedata
            %%
            d.Value = 0.75;
            d.Message = '显示计算结果';
            axe1 = app.UIAxes;
            cla(axe1)
            scatter3(axe1,x1(:,1),x1(:,2),x1(:,3));hold(axe1,"on");
            scatter3(axe1,res1_xCorrect(:,1),res1_xCorrect(:,2),res1_xCorrect(:,3),'r*');hold(axe1,"on");
            scatter3(axe1,data.mag1B_correct(:,1),data.mag1B_correct(:,2),data.mag1B_correct(:,3),'k*');hold(axe1,"on");
            grid(axe1,'on')
            title(axe1,'磁力计1')
            legend(axe1,'原始数据','离线标定值','在线标定值')
            %
            axe1 = app.UIAxes2;
            cla(axe1)
            scatter3(axe1,x2(:,1),x2(:,2),x2(:,3));hold(axe1,"on");
            scatter3(axe1,res2_xCorrect(:,1),res2_xCorrect(:,2),res2_xCorrect(:,3),'r*');hold(axe1,"on");
            scatter3(axe1,data.mag2B_correct(:,1),data.mag2B_correct(:,2),data.mag2B_correct(:,3),'k*');hold(axe1,"on");
            grid(axe1,'on')
            title(axe1,'磁力计2')
            legend(axe1,'原始数据','离线标定值','在线标定值')          
            %%
            app.UITable.RowName = ["k","b","A","","","meanErr","标定结果"];
            for i_mag = 1:2
               app.UITable.Data{1,i_mag} = k{i_mag}; 
               app.UITable.Data{2,i_mag} = str2mat(sprintf('[%.3f %.3f %.3f]',b{i_mag})); 
               app.UITable.Data{3,i_mag} = str2mat(sprintf('[%.3f %.3f %.3f;]',A{i_mag}(1,:))); 
               app.UITable.Data{4,i_mag} = str2mat(sprintf('[%.3f %.3f %.3f;]',A{i_mag}(2,:))); 
               app.UITable.Data{5,i_mag} = str2mat(sprintf('[%.3f %.3f %.3f;]',A{i_mag}(3,:))); 
               
            end
            for i = 1:3
                meanErr1(i) = mean(abs(data.mag1B_correct(:,i)-res1_xCorrect(:,i)));
                meanErr2(i) = mean(abs(data.mag2B_correct(:,i)-res2_xCorrect(:,i)));
            end
            app.UITable.Data{6,1} = str2mat(sprintf('[%.3f %.3f %.3f;]',meanErr1)); 
            app.UITable.Data{6,2} = str2mat(sprintf('[%.3f %.3f %.3f;]',meanErr2)); 
            
            if all(abs(meanErr1)<0.02)
                app.UITable.Data{7,1} = '很好'; 
                app.mag1Lamp.Color = 'green';
            elseif all(abs(meanErr1)<0.05)
                app.UITable.Data{7,1} = '可用'; 
                app.mag1Lamp.Color = 'blue';                
            else
                app.UITable.Data{7,1} = 'fail'; 
                app.mag1Lamp.Color = 'red';
            end
            if all(abs(meanErr2)<0.02)
                app.UITable.Data{7,2} = '很好'; 
                app.mag2Lamp.Color = 'green';
            elseif all(abs(meanErr2)<0.05)
                app.UITable.Data{7,2} = '可用'; 
                app.mag1Lamp.Color = 'blue';                   
            else
                app.UITable.Data{7,2} = 'fail';
                app.mag2Lamp.Color = 'red';
            end
            if all(abs(meanErr1)<0.02) && all(abs(meanErr2)<0.02)
                app.Res.Text = '标定数据正确';
                app.Res.BackgroundColor = 'green';
            elseif all(abs(meanErr1)<0.05) && all(abs(meanErr2)<0.05)
                app.Res.Text = '标定数据可用';
                app.Res.BackgroundColor = 'blue';
            else
                app.Res.Text = '需重新进行磁力计标定，联系研发';
                app.Res.BackgroundColor = 'red';
            end
        end

        % Callback function
        function ButtonPushed(app, event)
%             plot(app.UIAxes2,1:10)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {615, 615};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {361, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '磁力计标定结果验证程序';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'mag1'; 'mag2'};
            app.UITable.RowName = {'k;b;A;'};
            app.mag1LampLabel = uilabel(app.LeftPanel);
            app.mag1LampLabel.Text = 'mag1';
            app.mag1Lamp = uilamp(app.LeftPanel);
            app.mag2LampLabel = uilabel(app.LeftPanel);
            app.mag2LampLabel.Text = 'mag2';
            app.mag2Lamp = uilamp(app.LeftPanel);
            app.Res = uilabel(app.LeftPanel);
            app.Res.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.binMenu = uimenu(app.UIFigure);
            app.binMenu.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.binMenu.Text = '打开bin文件';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_magcalib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end